classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SkyplotPanel_2          matlab.ui.container.Panel
        GoundOverviewPanel      matlab.ui.container.Panel
        TimePanel               matlab.ui.container.Panel
        EditField_currenttime   matlab.ui.control.NumericEditField
        el_maskdegLabel_2       matlab.ui.control.Label
        Slider_time             matlab.ui.control.Slider
        TimeLabel               matlab.ui.control.Label
        positionPanel           matlab.ui.container.Panel
        EditField_h             matlab.ui.control.NumericEditField
        el_maskdegLabel_5       matlab.ui.control.Label
        EditField_lon           matlab.ui.control.NumericEditField
        el_maskdegLabel_4       matlab.ui.control.Label
        EditField_lat           matlab.ui.control.NumericEditField
        el_maskdegLabel_3       matlab.ui.control.Label
        EditField_elmask        matlab.ui.control.NumericEditField
        el_maskdegLabel         matlab.ui.control.Label
        SatelliteDropDown       matlab.ui.control.DropDown
        SatelliteDropDownLabel  matlab.ui.control.Label
        SkyplotPanel            matlab.ui.container.Panel
        GroundTrackPanel        matlab.ui.container.Panel
        PlottingOrbitLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        GPS % Description
        QZSS
        BDS
        time
        t
    end
    
    methods (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GPS = [];
            app.QZSS = [];
            app.BDS = [];
            app.time = 0;
            app.t = 0;
            %% 0.data
            load('nav.mat');
            nav.GPS.a = nav.GPS.a *10^-3; %(km)
            nav.QZSS.a = nav.QZSS.a*10^-3; %(km)
            nav.BDS.a = nav.BDS.a*10^-3; %(km)
            %% 1. v0(true anomaly at t0) (rad)
            % 1.(1) E0(eccentricity anomaly at t0) (rad)
            app.GPS.E0 = getE(nav.GPS.M0,nav.GPS.e);
            app.QZSS.E0 = getE(nav.QZSS.M0,nav.QZSS.e);
            app.BDS.E0 = getE(nav.BDS.M0,nav.BDS.e);
            
            % 1.(2) v0(true anomoaly at t0) (rad)
            app.GPS.nu0 = getnu(app.GPS.E0,nav.GPS.e);
            app.QZSS.nu0 = getnu(app.QZSS.E0,nav.QZSS.e);
            app.BDS.nu0 = getnu(app.BDS.E0,nav.BDS.e);

            %% 2. v(true anomaly at t)
            % 2.(1) n(mean motion) (rad/s)
            mu = 3.986004418 * 10^5; %km^3/s^-2
            app.GPS.n = sqrt(mu/nav.GPS.a^3);
            app.QZSS.n = sqrt(mu/nav.QZSS.a^3);
            app.BDS.n = sqrt(mu/nav.BDS.a^3);
            
            % 임의 시간 t
            app.GPS.t = datetime(nav.GPS.toc) + minutes(1:1440);
            app.QZSS.t = datetime(nav.QZSS.toc) + minutes(1:1440);
            app.BDS.t = datetime(nav.BDS.toc) + minutes(1:1440);
            
            for i = 1 : 1440
                % 2.(2) Mean anomaly at t (rad)
                app.GPS.M(i) = app.GPS.n*(60*i) + nav.GPS.M0 ;
                app.QZSS.M(i) = app.QZSS.n*(60*i) + nav.QZSS.M0;
                app.BDS.M(i) = app.BDS.n*(60*i) + nav.BDS.M0;
            
                %회전수 2pi*k 고려
                app.GPS.M(i) = rem(app.GPS.M(i),2*pi);     
                app.QZSS.M(i) = rem(app.QZSS.M(i),2*pi);    
                app.BDS.M(i) = rem(app.BDS.M(i),2*pi);
            
                % 2.(3) Eccentricity anomaly at t (rad)
                app.GPS.E(i) = getE(app.GPS.M(i),nav.GPS.e);
                app.QZSS.E(i) = getE(app.QZSS.M(i),nav.QZSS.e);
                app.BDS.E(i) = getE(app.BDS.M(i),nav.BDS.e);
            
                % 2.(4) true anomaly at t (rad)
                app.GPS.nu(i) = getnu(app.GPS.E(i),nav.GPS.e);
                app.QZSS.nu(i) = getnu(app.QZSS.E(i),nav.QZSS.e);
                app.BDS.nu(i) = getnu(app.BDS.E(i),nav.BDS.e);
            end

            %% 3. ECI r 구하기

            GPS_ECEF=[];
            QZSS_ECEF = [];
            BDS_ECEF = [];

            for i = 1 : 1440 
                % 3.(1) perifocal r
                GPS_PQ = solveRangeInPerifocalFrame(nav.GPS.a, nav.GPS.e, app.GPS.nu(i));
                QZSS_PQ = solveRangeInPerifocalFrame(nav.QZSS.a, nav.QZSS.e, app.QZSS.nu(i));
                BDS_PQ = solveRangeInPerifocalFrame(nav.BDS.a, nav.BDS.e, app.BDS.nu(i));
            
                % 3.(2) perifocal -> ECI 
                GPS_ECI = PQW2ECI(nav.GPS.omega, nav.GPS.i, nav.GPS.OMEGA) * GPS_PQ;
                QZSS_ECI = PQW2ECI(nav.QZSS.omega, nav.QZSS.i, nav.QZSS.OMEGA) * QZSS_PQ;
                BDS_ECI = PQW2ECI(nav.BDS.omega, nav.BDS.i, nav.BDS.OMEGA) * BDS_PQ;

                %% 4. ECI -> ECEF
                GPS_ECEF = [GPS_ECEF,ECI2ECEF_DCM(app.GPS.t(i))*GPS_ECI];
                QZSS_ECEF = [QZSS_ECEF,ECI2ECEF_DCM(app.QZSS.t(i))*QZSS_ECI];
                BDS_ECEF = [BDS_ECEF,ECI2ECEF_DCM(app.BDS.t(i))*BDS_ECI];
            end
            app.GPS.ECEF = GPS_ECEF';
            app.QZSS.ECEF = QZSS_ECEF';
            app.BDS.ECEF = BDS_ECEF';

            %% 5. ECEF -> GEODETIC,ECI
            wgs84 = wgs84Ellipsoid('kilometer');
            GPS_geo = [];
            QZSS_geo = [];
            BDS_geo = [];
            GPS_ENU = [];
            QZSS_ENU = [];
            BDS_ENU = [];

            for i = 1 : 1440
                % 5.(1) ECEF->GEODETIC
                [lat1,lon1,h1] = ecef2geodetic(wgs84,app.GPS.ECEF(i,1),app.GPS.ECEF(i,2),app.GPS.ECEF(i,3));
                GPS_geo = [GPS_geo,[lat1,lon1,h1]'];
            
                [lat2,lon2,h2] = ecef2geodetic(wgs84,app.QZSS.ECEF(i,1),app.QZSS.ECEF(i,2),app.QZSS.ECEF(i,3));
                QZSS_geo = [QZSS_geo,[lat2,lon2,h2]'];
            
                [lat3,lon3,h3] = ecef2geodetic(wgs84,app.BDS.ECEF(i,1),app.BDS.ECEF(i,2),app.BDS.ECEF(i,3));
                BDS_geo = [BDS_geo, [lat3,lon3,h3]'];
            
                % 5.(2)  ECEF->ENU
                [east1,north1,up1] = ecef2enu(app.GPS.ECEF(i,1),app.GPS.ECEF(i,2),app.GPS.ECEF(i,3),37,128,0,wgs84);
                GPS_ENU = [GPS_ENU, [east1,north1,up1]'];
            
                [east2,north2,up2] = ecef2enu(app.QZSS.ECEF(i,1),app.QZSS.ECEF(i,2),app.QZSS.ECEF(i,3),37,128,0,wgs84);
                QZSS_ENU = [QZSS_ENU, [east2,north2,up2]'];
            
                [east3,north3,up3] = ecef2enu(app.BDS.ECEF(i,1),app.BDS.ECEF(i,2),app.BDS.ECEF(i,3),37,128,0,wgs84);
                BDS_ENU = [BDS_ENU, [east3,north3,up3]'];
            end

            app.GPS.GEO = GPS_geo';
            app.QZSS.GEO = QZSS_geo';
            app.BDS.GEO = BDS_geo';
            app.GPS.ENU = GPS_ENU';
            app.QZSS.ENU = QZSS_ENU';
            app.BDS.ENU = BDS_ENU';

            app.GPS.Az = azimuth(app.GPS.ENU);
            app.GPS.El = elevation(app.GPS.ENU,10);
            app.QZSS.Az = azimuth(app.QZSS.ENU);
            app.QZSS.El = elevation(app.QZSS.ENU,10);
            app.BDS.Az = azimuth(app.BDS.ENU);
            app.BDS.El = elevation(app.BDS.ENU,10);

        end

        % Drop down opening function: SatelliteDropDown
        function SatelliteDropDownOpening(app, event)

        end

        % Callback function: Slider_time, Slider_time
        function Slider_timeValueChanging(app, event)
            changingValue = event.Value;
            gx = geoaxes(app.GroundTrackPanel);
            gx2 = geoaxes(app.GoundOverviewPanel);
            app.time = app.Slider_time.Value; 
            app.EditField_currenttime.Value = app.time;
            app.t = round(app.EditField_currenttime.Value) ;
            switch app.SatelliteDropDown.Value 
                    case 'GPS G20'
                        %Overview
                        geoplot(gx2,app.GPS.GEO(:,1),app.GPS.GEO(:,2),'*');
                        skyplot(app.SkyplotPanel_2,app.GPS.Az(1,:),app.GPS.El(1,:));
                        %Current Position
                        geoplot(gx,app.GPS.GEO(app.t*10,1),app.GPS.GEO(app.t*10,2),'*');
                        skyplot(app.SkyplotPanel,app.GPS.Az(1,app.t*10),app.GPS.El(1,app.t*10));
                        app.EditField_lat.Value = app.GPS.GEO(app.t*10,1);
                        app.EditField_lon.Value = app.GPS.GEO(app.t*10,2);
                        app.EditField_h.Value = app.GPS.GEO(app.t*10,3);
                    case 'QZSS J04'
                        %Overview
                        geoplot(gx2,app.QZSS.GEO(:,1),app.QZSS.GEO(:,2),'r*');
                        skyplot(app.SkyplotPanel_2,app.QZSS.Az(1,:),app.QZSS.El(1,:));
                        %Current Position
                        geoplot(gx,app.QZSS.GEO(app.t*10,1),app.QZSS.GEO(app.t*10,2),'r*');
                        skyplot(app.SkyplotPanel,app.QZSS.Az(1,app.t*10),app.QZSS.El(1,app.t*10));
                        app.EditField_lat.Value = app.QZSS.GEO(app.t*10,1);
                        app.EditField_lon.Value = app.QZSS.GEO(app.t*10,2);
                        app.EditField_h.Value = app.QZSS.GEO(app.t*10,3);
                    case 'BDS C40'
                        %Overview
                        geoplot(gx2,app.BDS.GEO(:,1),app.BDS.GEO(:,2),'g*');
                        skyplot(app.SkyplotPanel_2,app.BDS.Az(1,:),app.BDS.El(1,:));
                        %Current Position
                        geoplot(gx,app.BDS.GEO(app.t*10,1),app.BDS.GEO(app.t*10,2),'g*');
                        skyplot(app.SkyplotPanel,app.BDS.Az(1,app.t*10),app.BDS.El(1,app.t*10));
                        app.EditField_lat.Value = app.BDS.GEO(app.t*10,1);
                        app.EditField_lon.Value = app.BDS.GEO(app.t*10,2);
                        app.EditField_h.Value = app.BDS.GEO(app.t*10,3);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlottingOrbitLabel = uilabel(app.UIFigure);
            app.PlottingOrbitLabel.Text = 'Plotting Orbit';
            app.GroundTrackPanel = uipanel(app.UIFigure);
            app.GroundTrackPanel.Title = 'Ground Track';
            app.SkyplotPanel = uipanel(app.UIFigure);
            app.SkyplotPanel.Title = 'Skyplot';
            app.SatelliteDropDownLabel = uilabel(app.UIFigure);
            app.SatelliteDropDownLabel.Text = 'Satellite';
            app.SatelliteDropDown = uidropdown(app.UIFigure);
            app.SatelliteDropDown.Items = {'GPS G20', 'QZSS J04', 'BDS C40'};
            app.SatelliteDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SatelliteDropDownOpening, true);
            app.SatelliteDropDown.Value = 'BDS C40';
            app.positionPanel = uipanel(app.UIFigure);
            app.positionPanel.Title = 'position';
            app.el_maskdegLabel = uilabel(app.positionPanel);
            app.el_maskdegLabel.Text = 'el_mask(deg)';
            app.EditField_elmask = uieditfield(app.positionPanel, 'numeric');
            app.EditField_elmask.Value = 10;
            app.el_maskdegLabel_3 = uilabel(app.positionPanel);
            app.el_maskdegLabel_3.Text = 'lat(deg)';
            app.EditField_lat = uieditfield(app.positionPanel, 'numeric');
            app.EditField_lat.Value = 10;
            app.el_maskdegLabel_4 = uilabel(app.positionPanel);
            app.el_maskdegLabel_4.Text = 'lon(deg)';
            app.EditField_lon = uieditfield(app.positionPanel, 'numeric');
            app.EditField_lon.Value = 10;
            app.el_maskdegLabel_5 = uilabel(app.positionPanel);
            app.el_maskdegLabel_5.Text = 'h(km)';
            app.EditField_h = uieditfield(app.positionPanel, 'numeric');
            app.EditField_h.Value = 10;
            app.TimePanel = uipanel(app.UIFigure);
            app.TimePanel.Title = 'Time';
            app.TimeLabel = uilabel(app.TimePanel);
            app.TimeLabel.Text = 'Time (min)';
            app.Slider_time = uislider(app.TimePanel);
            app.Slider_time.Limits = [0 144];
            app.Slider_time.MajorTicks = [0 40 80 120 144];
            app.Slider_time.MajorTickLabels = {''};
            app.Slider_time.ValueChangedFcn = createCallbackFcn(app, @Slider_timeValueChanging, true);
            app.Slider_time.ValueChangingFcn = createCallbackFcn(app, @Slider_timeValueChanging, true);
            app.Slider_time.MinorTicks = [];
            app.el_maskdegLabel_2 = uilabel(app.TimePanel);
            app.el_maskdegLabel_2.Text = 'Current Time';
            app.EditField_currenttime = uieditfield(app.TimePanel, 'numeric');
            app.EditField_currenttime.Value = 10;
            app.GoundOverviewPanel = uipanel(app.UIFigure);
            app.GoundOverviewPanel.Title = 'GoundTrack';
            app.SkyplotPanel_2 = uipanel(app.UIFigure);
            app.SkyplotPanel_2.Title = 'Skyplot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end